classdef PreprocessingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreprocessingGUIUIFigure       matlab.ui.Figure
        MainTitleLabel                 matlab.ui.control.Label
        SAVEButton                     matlab.ui.control.Button
        MandatoryParametersPanel       matlab.ui.container.Panel
        SwitchSleep                    matlab.ui.control.Switch
        RejectasleepepochsLabel        matlab.ui.control.Label
        WouldyouliketoLabel            matlab.ui.control.Label
        MakeprocessingsemiautomaticLabel  matlab.ui.control.Label
        SwitchAuto                     matlab.ui.control.Switch
        ButtonSleep                    matlab.ui.control.Button
        WouldyouliketocomputeICALabel  matlab.ui.control.Label
        SwitchICA                      matlab.ui.control.Switch
        WouldyouliketouseASRLabel      matlab.ui.control.Label
        SwitchASR                      matlab.ui.control.Switch
        SecondsepochEditFieldLabel     matlab.ui.control.Label
        SecondsepochEditField          matlab.ui.control.NumericEditField
        HighPassEditFieldLabel         matlab.ui.control.Label
        HighPassEditField              matlab.ui.control.NumericEditField
        LowPassEditFieldLabel          matlab.ui.control.Label
        LowPassEditField               matlab.ui.control.NumericEditField
        FilteringLabel                 matlab.ui.control.Label
        WouldyouliketouseBLINKERLabel  matlab.ui.control.Label
        SwitchBLINKER                  matlab.ui.control.Switch
        OptionalParametersPanel        matlab.ui.container.Panel
        ButtonOptions                  matlab.ui.control.Button
        CleanLineButton                matlab.ui.control.Button
        ASRButton                      matlab.ui.control.Button
        ICAButton                      matlab.ui.control.Button
        BLINKERButton                  matlab.ui.control.Button
        OptionalsinglesubjectanalysesPanel  matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        GlobalPowerSpectraGPSLabel     matlab.ui.control.Label
        SwitchGPS                      matlab.ui.control.Switch
        GPSonScalpAreasLabel           matlab.ui.control.Label
        SwitchArea                     matlab.ui.control.Switch
        HelpAnalyses                   matlab.ui.control.Button
        FrequenciestoplotEditFieldLabel  matlab.ui.control.Label
        FrequenciestoplotEditField     matlab.ui.control.EditField
        ADDLINEButton                  matlab.ui.control.Button
        REMOVELINEButton               matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp   % Main app
        CurrentPath
        ParamPath
        
        % Default if the user do not click on the optional settings buttons
        CleanLineParam = {'50 100','0.01','2','4','4','100','2'};
        ASRParam = {'10',''};
        ICAParam={'2000'};
        BLINKERParam={'1'};
                    
        % Frequency bands
        FreqNames={'Delta';'Theta';'Alpha1';'Alpha2';'Beta1';'Beta2';'Gamma'};
        FreqRanges={'2 4';'5 7';'8 10';'11 12';'13 20';'20 30'; '31 48'};
        
        % Saving Within/Between number of factors
        WithinFactors
        BetweenFactors
        
        % List of electrode groups
        AreasList = {'AnteriorLeft AnteriorRight PosteriorLeft PosteriorRight',...
            '1 2 3 4 5 6 7 8 9 10 11','34 35 36 39 40 41 42 43 44 45 46','16 17 18 19 20 21 22 23 24 25 26 27',...
            '53 54 55 56 57 58 59 60 61 62 63 64','','','','','','','','','','','',''};
        AreasParam
        
        % Saving path
        SavePath
        
        % Time and date
        Date_Start
        
        % Knob value
        KnobVal 
    end
    
    properties (Access = private)
        % To establish connection with dialog box
        DialogApp 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Position, Path, ParamPath, WithinFactors, BetweenFactors, Date_Start, SavePath, KnobVal)
            % Retrieve current path
            app.CurrentPath=Path;
            
            % Retrieve path where to save results
            app.SavePath=SavePath;
            
            % Retrieve time and date 
            app.Date_Start = Date_Start;
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp=MainApp;
            
            % Saving Within/Between number of factors
            app.WithinFactors = WithinFactors;
            app.BetweenFactors = BetweenFactors;
            
            % Position the GUI at the center of the screen
            app.PreprocessingGUIUIFigure.Position=Position;
            
            % Editing baseline table data
            app.UITable.Data=[app.FreqNames app.FreqRanges];
            app.UITable.ColumnName={'Frequency Band Names','Frequency Ranges'};
            app.UITable.ColumnEditable=true;
            
            % Knob value
            app.KnobVal = KnobVal;
            
            % If parameters were loaded in the MAIN GUI
            if ~isempty(ParamPath)
                
                % To call the STUDY
                app.ParamPath=ParamPath;
                
                % Preprocessing
                PreprocStruct = load([ParamPath '\GUIPreProcessing.mat']);
                app.SwitchSleep.Value = PreprocStruct.Sleep;
                if strcmp(app.SwitchSleep.Value,'Yes')
                    app.SecondsepochEditField.Enable='on';
                    app.SecondsepochEditField.Value = PreprocStruct.SecondsEpoch;
                    app.SecondsepochEditFieldLabel.Enable = 'on';
                end
                app.SwitchAuto.Value = PreprocStruct.Automaticity;
                app.SwitchICA.Value = PreprocStruct.ICA;
                if strcmp(app.SwitchICA.Value,'Yes')
                    app.ICAButton.Enable = 'on';
                    app.ICAParam = PreprocStruct.AnswerICA;
                else
                    app.ICAButton.Enable = 'off';
                end
                app.SwitchASR.Value = PreprocStruct.ASR;
                if strcmp(app.SwitchASR.Value,'Yes')
                    app.ASRButton.Enable = 'on';
                    app.ASRParam = PreprocStruct.AnswerASR';
                else
                    app.ASRButton.Enable = 'off';
                end
                app.LowPassEditField.Value = PreprocStruct.LowPass;
                app.HighPassEditField.Value = PreprocStruct.HighPass;
                
                app.SwitchBLINKER.Value=PreprocStruct.BLINKER;
                if strcmpi(app.SwitchBLINKER.Value,'Yes')
                    app.BLINKERButton.Enable = 'on'; 
                else
                    app.BLINKERButton.Enable = 'off'; 
                end
                
                % Cleanline 
                app.CleanLineParam = PreprocStruct.AnswerCleanLine';
                
                % Analysis
                AnalysisStruct = load([app.ParamPath '\AnalysesGUI.mat']);
                app.SwitchGPS.Value = AnalysisStruct.AnalysesSwitch{1,2};
                if strcmp(app.SwitchGPS.Value,'Yes')
                    app.SwitchArea.Enable = 'on';
                    app.SwitchArea.Value = AnalysisStruct.AnalysesSwitch{2,2};
                    app.FrequenciestoplotEditField.Enable = 'on';
                    app.FrequenciestoplotEditFieldLabel.Enable = 'on';
                    app.FrequenciestoplotEditField.Value = num2str(AnalysisStruct.FreqsToPlot);
                    app.UITable.Enable = 'on';
                    
                    % Areas
                    app.AreasList=AnalysisStruct.AreasList;
                    
                    % Frequencies
                    app.UITable.Data=[AnalysisStruct.FreqData(:,1) AnalysisStruct.FreqData(:,2)];
                end
            end
        end

        % Button pushed function: ButtonSleep
        function ButtonSleepPushed(app, event)
           Text=['Information regarding PREPROCESSING options:' ...
                newline  ...
                newline '1) REJECT ASLEEP EPOCHS:'...
                newline 'This homemade method will, first, temporarily epoch the continuous files (epoch time window user-defined, in seconds). Then, time-frequency decomposition (morlet wavelet) will be applied to the whole file.'...
                newline 'Thirdly, global power spectra (GPS) will be averaged for the Alpha (8-12Hz) and Theta (4-7Hz) bands. Epochs will be tagged as "Asleep" when the ratio of Alpha/Theta is < 1.'...
                newline 'Epochs tagged "Asleep" will ultimately be rejected.'...
                newline 'The goal is to only keep epochs while participants are awake. The method is inspired from: Diaz et al. 2016, Frontiers in Psychology.'...
                newline ...
                newline '2) SEMI-AUTOMATIC:'...
                newline 'You have the possibility to make the software semi-automatic. This implies that for each file, the process will be paused to allow you to have a look at the preprocessing effects (difference before/after applying preprocessing steps).'...
                newline 'If you decide to compute Independent Component Analysis (ICA), the components rejection stage will inevitably be semi-automatic.'...
                newline ...
                newline '3) COMPUTE ICA:'...
                newline 'Independent Component Analysis (ICA) will be computed with the AMICA plugin. This plugin requires the installation of the freeware "Mpich2_1.4".'...
                newline 'RESTINGLAB should detect whether Mpich is not installed and proceed to its installation. If you nevertheless encounter errors with AMICA, please install Mpich manually:'...
                newline 'http://www.mpich.org/static/downloads/1.4/'...
                newline ...
                newline '4) ARTIFACT SUBSPACE RECONSTRUCTION (ASR):'...
                newline 'By default ASR is activated (recommended). ASR will "The basic principle is to first find a section of data that represents clean "reference" EEG and to compute statistics on there.'...
                newline 'Then, the function goes over the whole data in a sliding window and finds the subspaces in which there is activity that is more than a few standard deviations away from the reference EEG (this threshold is a tunable parameter).'...
                newline 'Once the function has found the bad subspaces it will treat them as missing data and reconstruct their content using a mixing matrix that was calculated on the clean data."'...
                newline 'ASR drastically reduces the number of electrodes that would be interpolated otherwise.'];
            
            app.DialogApp = InformationGUI(app.PreprocessingGUIUIFigure.Position, Text);
        end

        % Button pushed function: ButtonOptions
        function ButtonOptionsPushed(app, event)
            Text=['Information regarding optional parameters settings:' ...
                newline '1) CLEANLINE'...
                newline 'a) Line Noise = Line noise frequencies to remove.'...
                newline 'b) P-val = p-value for detection of significant sinusoid'...
                newline 'c) Bandwidth = width of a spectral peak for a sinusoid at fixed frequency. As such, this defines the multi-taper frequency resolution.'...
                newline 'd) Sliding window length = Default is the epoch length (in seconds).'...
                newline 'e) Sliding window step = This determines the amount of overlap between sliding windows. Default is window length (no overlap).' ...
                newline 'f) Window overlap = A value of 1 means (nearly) linear smoothing between adjacent sliding windows. A value of Inf means no smoothing. Intermediate values produce sigmoidal smoothing between adjacent windows.'...
                newline 'g) FFT padding factor = Signal will be zero-padded to the desired power of two greater than the sliding window length.'...
                newline ''...
                newline '2) ASR'...
                newline 'a) Repair bursts (std) = Criterion for projecting local bursts out of the data. This is in standard deviations from clean EEG at which a signal component would be considered a burst artifact. Generally a lower value makes the criterion more aggressive.'...
                newline 'b) Remove time windows = Criterion for removing bad time windows. This is the maximum fraction of bad channels that are tolerated in the final output data for each considered window. Generally a lower value makes the criterion more aggressive.'...
                newline ''...
                newline '3) ICA'...
                newline 'a)Number of iterations = maximum number of iterations to perform, def=2000.'];
            
            app.DialogApp = InformationGUI(app.PreprocessingGUIUIFigure.Position, Text);
        end

        % Button pushed function: CleanLineButton
        function CleanLineButtonPushed(app, event)
            % Temporary, should be replaced with a normal GUI
            PromptSinus={ 'Line noise frequencies to remove',...
                'P-value for detection of significant sinusoid'...
                'Bandwidth [Hz]','Sliding window length (sec)'...
                'Sliding window step size (sec)','Window overlap smoothing factor'...
                'FFT padding factor'};
            app.CleanLineParam=inputdlg(PromptSinus,'Cleanline',1,app.CleanLineParam);
        end

        % Button pushed function: ASRButton
        function ASRButtonPushed(app, event)
            % Temporary, should be replaced with a normal GUI
            PromptChannels={'Repair bursts using ASR (std)'...
                ['Remove time windows (0-1) [0.25 is recommended]'...
                newline 'Careful, it will REMOVE TIME PERIODS CONTAINING ARTIFACTS.']};
            app.ASRParam=inputdlg(PromptChannels,'ASR',1,app.ASRParam);
        end

        % Value changed function: SwitchGPS
        function SwitchGPSValueChanged(app, event)
            if strcmp(app.SwitchGPS.Value,'Yes')
                app.GPSonScalpAreasLabel.Enable = 'on';
                app.SwitchArea.Enable = 'on';
                app.UITable.Enable = 'on';
                app.FrequenciestoplotEditField.Enable = 'on';
                app.FrequenciestoplotEditFieldLabel.Enable = 'on';
            else
                app.GPSonScalpAreasLabel.Enable = 'off';
                app.SwitchArea.Enable = 'off';
                app.UITable.Enable = 'off';
                app.FrequenciestoplotEditField.Enable = 'off';
                app.FrequenciestoplotEditFieldLabel.Enable = 'off';
            end
        end

        % Value changed function: SwitchArea
        function SwitchAreaValueChanged(app, event)
            if strcmp(app.SwitchArea.Value,'Yes')
                % Temporary, should be replaced with a normal GUI
                PromptAreas={['Grouping electrodes in areas to compute GPS.'...
                    newline '1) Write the name of the headers (separated by a space)'],...
                    ['2)For each header write the list of channels (numbers or name, separated by a space, on a each line)',...
                    newline 'Group 1:'],'Group 2:','Group 3:','Group 4:','Group 5:','Group 6:','Group 7:','Group 8:',...
                    'Group 9:','Group 10:','Group 11:','Group 12:','Group 13:','Group 14:','Group 15:','Group 16:'};
                app.AreasList=inputdlg(PromptAreas,'AreasList',1,app.AreasList);
            end
        end

        % Value changed function: SwitchASR
        function SwitchASRValueChanged(app, event)
            if strcmp(app.SwitchASR.Value,'Yes')
                app.ASRButton.Enable = 'on';
            else
                app.ASRButton.Enable = 'off';
            end
        end

        % Value changed function: SwitchICA
        function SwitchICAValueChanged(app, event)
            if strcmp(app.SwitchICA.Value,'Yes')
                app.ICAButton.Enable = 'on';
            else
                app.ICAButton.Enable = 'off';
            end
        end

        % Button pushed function: ICAButton
        function ICAButtonPushed(app, event)
            % Temporary, should be replaced with a normal GUI
            PromptICA={'Number of iterations'};
            app.ICAParam=inputdlg(PromptICA,'ICA',1,app.ICAParam);
        end

        % Value changed function: SwitchSleep
        function SwitchSleepValueChanged(app, event)
           if strcmp(app.SwitchSleep.Value,'Yes')
               app.SecondsepochEditFieldLabel.Enable = 'on';
               app.SecondsepochEditField.Enable = 'on';
           else
               app.SecondsepochEditFieldLabel.Enable = 'off';
               app.SecondsepochEditField.Enable = 'off';
           end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
             % Saving properties in a .mat file     
            Sleep=app.SwitchSleep.Value;
            Automaticity=app.SwitchAuto.Value;
            ICA=app.SwitchICA.Value;
            ASR=app.SwitchASR.Value;
            AnswerCleanLine=app.CleanLineParam;
            AnswerASR=app.ASRParam;
            SecondsEpoch=app.SecondsepochEditField.Value;
            HighPass=app.HighPassEditField.Value;
            LowPass=app.LowPassEditField.Value;
            BLINKER=app.SwitchBLINKER.Value;
            AnswerBLINKER=app.BLINKERParam;
            if strcmp(app.ICAButton.Enable,'on')
                AnswerICA=app.ICAParam;
                save([app.SavePath '\Parameters\' app.Date_Start '\GUIPreProcessing.mat'],'Sleep','Automaticity','ICA','ASR','AnswerCleanLine','AnswerASR',...
                    'SecondsEpoch','LowPass','HighPass','AnswerICA','BLINKER','AnswerBLINKER');
            else
                save([app.SavePath '\Parameters\' app.Date_Start '\GUIPreProcessing.mat'],'Sleep','Automaticity','ICA','ASR','AnswerCleanLine','AnswerASR',...
                    'SecondsEpoch','LowPass','HighPass','BLINKER','AnswerBLINKER');
            end

                        
            % Save analyses data only if selected
            if strcmp(app.SwitchGPS.Value,'Yes')
                Measures = {'SwitchGPS';'SwitchArea'};
                % Preparing matrix to export
                AnalysesSwitch=[Measures cell(2,1)];
                
                for k=1:length(Measures)
                    AnalysesSwitch{k,2}=app.(Measures{k}).Value;
                end

                % Saving parameters as .mat file
                AreasList=app.AreasList;
                FreqData=app.UITable.Data;
                FreqsToPlot=str2num(app.FrequenciestoplotEditField.Value);
                save([app.SavePath '\Parameters\' app.Date_Start '\AnalysesGUI.mat'],'AnalysesSwitch','AreasList','FreqData','FreqsToPlot'); 
                
                % Creating exporting folders corresponding to the definition in the frequency bands table
%                 for k=1:length(FreqData(:,1))
%                    if ~exist([app.SavePath '\Exports\SourceLocalisation\' app.Date_Start '\' FreqData{k,1}],'dir')
%                     mkdir ([app.SavePath '\Exports\SourceLocalisation\' app.Date_Start '\' FreqData{k,1}]) 
%                    end
%                 end
                
            end
            
            % Send values back to main window by calling its public function
            updateMainGUI3(app.CallingApp);
            
            % If analyses are not selected (i.e. only preprocessing)
            if strcmpi(app.KnobVal,'Preprocessing')
                updateMainGUI4(app.CallingApp);
            end
            
            % delete options dialog
            delete(app)
        end

        % Button pushed function: ADDLINEButton
        function ADDLINEButtonPushed(app, event)
            % Frequency bands
            app.FreqNames(length(app.FreqNames)+1,:)={''};
            app.FreqRanges(length(app.FreqRanges)+1,:)={''};
            
            % Editing baseline table data
            app.UITable.Data=[app.FreqNames app.FreqRanges];
        end

        % Button pushed function: REMOVELINEButton
        function REMOVELINEButtonPushed(app, event)
            % Frequency bands
            app.FreqNames(length(app.FreqNames),:)=[];
            app.FreqRanges(length(app.FreqRanges),:)=[];
            
            % Editing baseline table data
            app.UITable.Data=[app.FreqNames app.FreqRanges];
        end

        % Button pushed function: HelpAnalyses
        function HelpAnalysesPushed(app, event)
             Text=['Information regarding optional analyses settings:' ...
                newline '1) GLOBAL POWER SPECTRA (GPS)'...
                newline 'You can compute the Power Spectral Density (PSD) for all electrodes in the frequency bands and associated ranges specified in the table below.'...
                newline 'PSD for each electrode, averaged over each frequency band will be exported in the GPS excel templates.'...
                newline 'Individual subjects plot will be exported with topographical plots at specific frequencies (i.e. Frequencies to plot).'...
                newline ...
                newline '2) GPS ON SCALP AREAS'...
                newline 'There is a possibility to calculate average GPS for groups of electrodes (i.e. Scalp Areas) which will be exported in the AreaAmplitude excel templates.'...
                newline 'See Grabner and De Smedt, 2012, Frontiers in Psychology.'...
                newline ...
                newline '2) STUDY SETTINGS'...
                newline 'The STUDY button enables to define statistics for group analyses (based on the design defined in the GUI).'];
            
            app.DialogApp = InformationGUI(app.PreprocessingGUIUIFigure.Position, Text);
        end

        % Callback function
        function STUDYsettingsButtonPushed(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = StudyGUI(app,app.PreprocessingGUIUIFigure.Position,app.CurrentPath,app.ParamPath,app.WithinFactors,app.BetweenFactors);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Editing baseline table data
            app.FreqNames = (app.UITable.Data(:,1));
            app.FreqRanges = (app.UITable.Data(:,2));
        end

        % Value changed function: SwitchBLINKER
        function SwitchBLINKERValueChanged(app, event)
            value = app.SwitchBLINKER.Value;
            if strcmpi(app.SwitchBLINKER.Value,'Yes')
                app.BLINKERButton.Enable = 'on'; 
            else
                app.BLINKERButton.Enable = 'off'; 
            end
        end

        % Button pushed function: BLINKERButton
        function BLINKERButtonPushed(app, event)
            % Temporary, should be replaced with a normal GUI
            PromptBlinks={['Would you like to:',...
                newline,'1) Reject portions of data containing blinks (not recommended for time-sensitive analyses)',...
                newline,'2) Interpolate portions containing blinks using ASR (ASR will be enabled)']};
            app.BLINKERParam=inputdlg(PromptBlinks,'BLINKER',1,app.BLINKERParam);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreprocessingGUIUIFigure = uifigure('Visible', 'off');
            app.PreprocessingGUIUIFigure.Name = 'PreprocessingGUI';
            app.MainTitleLabel = uilabel(app.PreprocessingGUIUIFigure);
            app.MainTitleLabel.Text = 'EEG Preprocessing';
            app.SAVEButton = uibutton(app.PreprocessingGUIUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.MandatoryParametersPanel = uipanel(app.PreprocessingGUIUIFigure);
            app.MandatoryParametersPanel.Title = 'Mandatory Parameters';
            app.SwitchSleep = uiswitch(app.MandatoryParametersPanel, 'slider');
            app.SwitchSleep.Items = {'No', 'Yes'};
            app.SwitchSleep.ValueChangedFcn = createCallbackFcn(app, @SwitchSleepValueChanged, true);
            app.SwitchSleep.Value = 'No';
            app.RejectasleepepochsLabel = uilabel(app.MandatoryParametersPanel);
            app.RejectasleepepochsLabel.Text = '1) Reject asleep epochs ?';
            app.WouldyouliketoLabel = uilabel(app.MandatoryParametersPanel);
            app.WouldyouliketoLabel.Text = 'Would you like to...';
            app.MakeprocessingsemiautomaticLabel = uilabel(app.MandatoryParametersPanel);
            app.MakeprocessingsemiautomaticLabel.Text = '2) Make processing semi-automatic ?';
            app.SwitchAuto = uiswitch(app.MandatoryParametersPanel, 'slider');
            app.SwitchAuto.Items = {'No', 'Yes'};
            app.SwitchAuto.Value = 'No';
            app.ButtonSleep = uibutton(app.MandatoryParametersPanel, 'push');
            app.ButtonSleep.ButtonPushedFcn = createCallbackFcn(app, @ButtonSleepPushed, true);
            app.ButtonSleep.Text = '?';
            app.WouldyouliketocomputeICALabel = uilabel(app.MandatoryParametersPanel);
            app.WouldyouliketocomputeICALabel.Text = '3) Would you like to compute ICA ?';
            app.SwitchICA = uiswitch(app.MandatoryParametersPanel, 'slider');
            app.SwitchICA.Items = {'No', 'Yes'};
            app.SwitchICA.ValueChangedFcn = createCallbackFcn(app, @SwitchICAValueChanged, true);
            app.SwitchICA.Value = 'No';
            app.WouldyouliketouseASRLabel = uilabel(app.MandatoryParametersPanel);
            app.WouldyouliketouseASRLabel.Text = '4) Would you like to use ASR ?';
            app.SwitchASR = uiswitch(app.MandatoryParametersPanel, 'slider');
            app.SwitchASR.Items = {'No', 'Yes'};
            app.SwitchASR.ValueChangedFcn = createCallbackFcn(app, @SwitchASRValueChanged, true);
            app.SwitchASR.Value = 'Yes';
            app.SecondsepochEditFieldLabel = uilabel(app.MandatoryParametersPanel);
            app.SecondsepochEditFieldLabel.Enable = 'off';
            app.SecondsepochEditFieldLabel.Text = 'Seconds/epoch';
            app.SecondsepochEditField = uieditfield(app.MandatoryParametersPanel, 'numeric');
            app.SecondsepochEditField.Limits = [1 Inf];
            app.SecondsepochEditField.Enable = 'off';
            app.SecondsepochEditField.Value = 30;
            app.HighPassEditFieldLabel = uilabel(app.MandatoryParametersPanel);
            app.HighPassEditFieldLabel.Text = 'High Pass';
            app.HighPassEditField = uieditfield(app.MandatoryParametersPanel, 'numeric');
            app.HighPassEditField.Limits = [0 Inf];
            app.HighPassEditField.Value = 1;
            app.LowPassEditFieldLabel = uilabel(app.MandatoryParametersPanel);
            app.LowPassEditFieldLabel.Text = 'Low Pass';
            app.LowPassEditField = uieditfield(app.MandatoryParametersPanel, 'numeric');
            app.LowPassEditField.Limits = [0 Inf];
            app.FilteringLabel = uilabel(app.MandatoryParametersPanel);
            app.FilteringLabel.Text = '6) Filtering:';
            app.WouldyouliketouseBLINKERLabel = uilabel(app.MandatoryParametersPanel);
            app.WouldyouliketouseBLINKERLabel.Text = '5) Would you like to use BLINKER ?';
            app.SwitchBLINKER = uiswitch(app.MandatoryParametersPanel, 'slider');
            app.SwitchBLINKER.Items = {'No', 'Yes'};
            app.SwitchBLINKER.ValueChangedFcn = createCallbackFcn(app, @SwitchBLINKERValueChanged, true);
            app.SwitchBLINKER.Value = 'No';
            app.OptionalParametersPanel = uipanel(app.PreprocessingGUIUIFigure);
            app.OptionalParametersPanel.Title = 'Optional Parameters';
            app.ButtonOptions = uibutton(app.OptionalParametersPanel, 'push');
            app.ButtonOptions.ButtonPushedFcn = createCallbackFcn(app, @ButtonOptionsPushed, true);
            app.ButtonOptions.Text = '?';
            app.CleanLineButton = uibutton(app.OptionalParametersPanel, 'push');
            app.CleanLineButton.ButtonPushedFcn = createCallbackFcn(app, @CleanLineButtonPushed, true);
            app.CleanLineButton.Text = 'CleanLine';
            app.ASRButton = uibutton(app.OptionalParametersPanel, 'push');
            app.ASRButton.ButtonPushedFcn = createCallbackFcn(app, @ASRButtonPushed, true);
            app.ASRButton.Text = 'ASR';
            app.ICAButton = uibutton(app.OptionalParametersPanel, 'push');
            app.ICAButton.ButtonPushedFcn = createCallbackFcn(app, @ICAButtonPushed, true);
            app.ICAButton.Enable = 'off';
            app.ICAButton.Text = 'ICA';
            app.BLINKERButton = uibutton(app.OptionalParametersPanel, 'push');
            app.BLINKERButton.ButtonPushedFcn = createCallbackFcn(app, @BLINKERButtonPushed, true);
            app.BLINKERButton.Enable = 'off';
            app.BLINKERButton.Text = 'BLINKER';
            app.OptionalsinglesubjectanalysesPanel = uipanel(app.PreprocessingGUIUIFigure);
            app.OptionalsinglesubjectanalysesPanel.Title = 'Optional single-subject analyses';
            app.UITable = uitable(app.OptionalsinglesubjectanalysesPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Enable = 'off';
            app.GlobalPowerSpectraGPSLabel = uilabel(app.OptionalsinglesubjectanalysesPanel);
            app.GlobalPowerSpectraGPSLabel.Text = '1. Global Power Spectra (GPS)';
            app.SwitchGPS = uiswitch(app.OptionalsinglesubjectanalysesPanel, 'slider');
            app.SwitchGPS.Items = {'No', 'Yes'};
            app.SwitchGPS.ValueChangedFcn = createCallbackFcn(app, @SwitchGPSValueChanged, true);
            app.SwitchGPS.Value = 'No';
            app.GPSonScalpAreasLabel = uilabel(app.OptionalsinglesubjectanalysesPanel);
            app.GPSonScalpAreasLabel.Enable = 'off';
            app.GPSonScalpAreasLabel.Text = '2. GPS on Scalp Areas ';
            app.SwitchArea = uiswitch(app.OptionalsinglesubjectanalysesPanel, 'slider');
            app.SwitchArea.Items = {'No', 'Yes'};
            app.SwitchArea.ValueChangedFcn = createCallbackFcn(app, @SwitchAreaValueChanged, true);
            app.SwitchArea.Enable = 'off';
            app.SwitchArea.Value = 'No';
            app.HelpAnalyses = uibutton(app.OptionalsinglesubjectanalysesPanel, 'push');
            app.HelpAnalyses.ButtonPushedFcn = createCallbackFcn(app, @HelpAnalysesPushed, true);
            app.HelpAnalyses.Text = '?';
            app.FrequenciestoplotEditFieldLabel = uilabel(app.OptionalsinglesubjectanalysesPanel);
            app.FrequenciestoplotEditFieldLabel.Enable = 'off';
            app.FrequenciestoplotEditFieldLabel.Text = 'Frequencies to plot';
            app.FrequenciestoplotEditField = uieditfield(app.OptionalsinglesubjectanalysesPanel, 'text');
            app.FrequenciestoplotEditField.Enable = 'off';
            app.FrequenciestoplotEditField.Value = '15 20 25';
            app.ADDLINEButton = uibutton(app.PreprocessingGUIUIFigure, 'push');
            app.ADDLINEButton.ButtonPushedFcn = createCallbackFcn(app, @ADDLINEButtonPushed, true);
            app.ADDLINEButton.Text = 'ADD LINE';
            app.REMOVELINEButton = uibutton(app.PreprocessingGUIUIFigure, 'push');
            app.REMOVELINEButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVELINEButtonPushed, true);
            app.REMOVELINEButton.Text = 'REMOVE LINE';
            % Show the figure after all components are created
            app.PreprocessingGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreprocessingGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PreprocessingGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreprocessingGUIUIFigure)
        end
    end
end